classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID5CUIFigure            matlab.ui.Figure
        ParametersInitialConiditionsPanel  matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        StudentsTab                matlab.ui.container.Tab
        soSC_Field                 matlab.ui.control.NumericEditField
        soSNC_Field                matlab.ui.control.NumericEditField
        InitialConditionsPanel     matlab.ui.container.Panel
        icSExpC_Field              matlab.ui.control.NumericEditField
        icSRecC_Field              matlab.ui.control.NumericEditField
        icSExpNC_Field             matlab.ui.control.NumericEditField
        icSRecNC_Field             matlab.ui.control.NumericEditField
        ExposedCompliantLabel      matlab.ui.control.Label
        icSExpC_Slider             matlab.ui.control.Slider
        RecoveredCompliantLabel    matlab.ui.control.Label
        icSRecC_Slider             matlab.ui.control.Slider
        ExposedNonCLabel           matlab.ui.control.Label
        icSExpNC_Slider            matlab.ui.control.Slider
        RecoveredNonCLabel         matlab.ui.control.Label
        icSRecNC_Slider            matlab.ui.control.Slider
        InfectedContactsweekCompliantLabel  matlab.ui.control.Label
        soSC_Slider                matlab.ui.control.Slider
        InfectedContactsweekNoncompliantLabel  matlab.ui.control.Label
        soSNC_Slider               matlab.ui.control.Slider
        AdminTeachersTab           matlab.ui.container.Tab
        InitialConditionsPanel_2   matlab.ui.container.Panel
        icATExpHR_Field            matlab.ui.control.NumericEditField
        icATRecHR_Field            matlab.ui.control.NumericEditField
        icATExpLR_Field            matlab.ui.control.NumericEditField
        icATRecLR_Field            matlab.ui.control.NumericEditField
        ExposedHighriskLabel       matlab.ui.control.Label
        icATExpHR_Slider           matlab.ui.control.Slider
        ExposedLowriskLabel        matlab.ui.control.Label
        icATExpLR_Slider           matlab.ui.control.Slider
        RecoveredLowriskLabel      matlab.ui.control.Label
        icATRecLR_Slider           matlab.ui.control.Slider
        RecoveredHighriskLabel     matlab.ui.control.Label
        icATRecHR_Slider           matlab.ui.control.Slider
        soATHR_Field               matlab.ui.control.NumericEditField
        soATLR_Field               matlab.ui.control.NumericEditField
        InfectedContactsweekHighriskLabel  matlab.ui.control.Label
        soATHR_Slider              matlab.ui.control.Slider
        InfectedContactsweekLowriskLabel  matlab.ui.control.Label
        soATLR_Slider              matlab.ui.control.Slider
        StaffTab                   matlab.ui.container.Tab
        InitialConditionsPanel_3   matlab.ui.container.Panel
        soStaffHR_Field            matlab.ui.control.NumericEditField
        soStaffLR_Field            matlab.ui.control.NumericEditField
        InfectedContactsweekHighriskLabel_2  matlab.ui.control.Label
        soStaffHR_Slider           matlab.ui.control.Slider
        InfectedContactsweekLowriskLabel_2  matlab.ui.control.Label
        soStaffLR_Slider           matlab.ui.control.Slider
        icStaffExpLR_Field         matlab.ui.control.NumericEditField
        icStaffRecLR_Field         matlab.ui.control.NumericEditField
        icStaffExpHR_Field         matlab.ui.control.NumericEditField
        icStaffRecHR_Field         matlab.ui.control.NumericEditField
        ExposedHighriskLabel_2     matlab.ui.control.Label
        icStaffExpHR_Slider        matlab.ui.control.Slider
        RecoveredHighriskLabel_2   matlab.ui.control.Label
        icStaffRecHR_Slider        matlab.ui.control.Slider
        ExposedLowriskLabel_2      matlab.ui.control.Label
        icStaffExpLR_Slider        matlab.ui.control.Slider
        RecoveredLowriskLabel_2    matlab.ui.control.Label
        icStaffRecLR_Slider        matlab.ui.control.Slider
        TracingEffectivenessLabel  matlab.ui.control.Label
        scaleTracking_Slider       matlab.ui.control.Slider
        scaleTracking_Field        matlab.ui.control.NumericEditField
        incAsymp_Field             matlab.ui.control.NumericEditField
        AsymptomaticInfectLabel    matlab.ui.control.Label
        incAsymp_Slider            matlab.ui.control.Slider
        propNC_Field               matlab.ui.control.NumericEditField
        NonCompliantLabel          matlab.ui.control.Label
        propNC_Slider              matlab.ui.control.Slider
        InputPreset1_Button        matlab.ui.control.Button
        InputPreset2_Button        matlab.ui.control.Button
        InputPreset3_Button        matlab.ui.control.Button
        betaScaling_Field          matlab.ui.control.NumericEditField
        BetaScalingSliderLabel     matlab.ui.control.Label
        betaScaling_Slider         matlab.ui.control.Slider
        PlotsTabGroup              matlab.ui.container.TabGroup
        PlotsDailyTab              matlab.ui.container.Tab
        UIAxes1                    matlab.ui.control.UIAxes
        AT_HRCheckBox              matlab.ui.control.CheckBox
        AT_LRCheckBox              matlab.ui.control.CheckBox
        Staff_HRCheckBox           matlab.ui.control.CheckBox
        Staff_LRCheckBox           matlab.ui.control.CheckBox
        Students_CCheckBox         matlab.ui.control.CheckBox
        Students_NCCheckBox        matlab.ui.control.CheckBox
        ShowSusceptibleCheckBox    matlab.ui.control.CheckBox
        UIAxes2                    matlab.ui.control.UIAxes
        ShowHeldCheckBox           matlab.ui.control.CheckBox
        PlotscumulativeTab         matlab.ui.container.Tab
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes4                    matlab.ui.control.UIAxes
        AT_HRCheckBox_C            matlab.ui.control.CheckBox
        AT_LRCheckBox_C            matlab.ui.control.CheckBox
        Staff_HRCheckBox_C         matlab.ui.control.CheckBox
        Staff_LRCheckBox_C         matlab.ui.control.CheckBox
        Students_CCheckBox_C       matlab.ui.control.CheckBox
        Students_NCCheckBox_C      matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        inputs = AppInputs()
        dailyResults %= DailyResults()
        cumulativeResults %= CumulativeResults()
        selection = [1, 1, 1, 1, 1, 1]
        selection_C = [1, 1, 1, 1, 1, 1]
        showHeld = 1
        showSusceptible = 1
    end
    
    
    methods (Access = private)
        function redraw(app)
            %disp(app.results.y);
            
            t = app.dailyResults.t;
            data = app.dailyResults.selectPops(app.selection);
            
            suspopcolor = 1/255*[16 49 179];
            exppopcolor = 1/255*[102 133 255];
            infpopcolor = 1/255*[255 75 49];
            recpopcolor = 1/255*[52 179 126];
            medpopcolor = 1/255*[255 149 49];
            heldpopcolor = 1/255*[98 140 202];
            
            % Plot in upper graph window
            cla(app.UIAxes1);
            hold(app.UIAxes1, "on");
            
            if app.showSusceptible
                plot(app.UIAxes1, t,data.Sus,'color', suspopcolor, 'LineWidth', 1.5) % susceptible people
                plot(app.UIAxes1, t,data.Exp,'color', exppopcolor, 'LineWidth', 1.5) %  exposed people
                plot(app.UIAxes1, t,data.Inf,'color', infpopcolor, 'LineWidth', 1.5) %  infected people
                plot(app.UIAxes1, t,data.Rec,'color', recpopcolor, 'LineWidth', 1.5) % recovered people
                xlabel(app.UIAxes1, "Susceptible / Exposed / Infected  / Recovered");
                legend(app.UIAxes1,'Susceptible','Exposed','Infected','Recovered');
            else
                plot(app.UIAxes1, t,data.Exp,'color', exppopcolor, 'LineWidth', 1.5) %  exposed people
                plot(app.UIAxes1, t,data.Inf,'color', infpopcolor, 'LineWidth', 1.5) %  infected people
                plot(app.UIAxes1, t,data.Rec,'color', recpopcolor, 'LineWidth', 1.5) % recovered people
                xlabel(app.UIAxes1, "Exposed / Infected / Recovered");
                legend(app.UIAxes1, 'Exposed','Infected','Recovered');
            end
            ylabel(app.UIAxes1, "");
            grid(app.UIAxes1, 'on');
            
            % Plot in lower graph window
            cla(app.UIAxes2);
            hold(app.UIAxes2, "on");
            plot(app.UIAxes2, t,data.Inf,'color', infpopcolor, 'LineWidth', 1.5) % infected
            plot(app.UIAxes2, t,data.Med,'color', medpopcolor, 'LineWidth', 1.5) % medical
            plot(app.UIAxes2, t,data.Dead,'k', 'LineWidth', 1.5) % deads
            if app.showHeld
                plot(app.UIAxes2, t,data.Held,'color', heldpopcolor, 'LineWidth', 1.5) %  held
                legend(app.UIAxes2, 'Infected','Medical','Dead','Held');
                xlabel(app.UIAxes2, "Infected / Medical / Dead / Held ");
            else
                legend(app.UIAxes2, 'Infected','Medical','Dead');
                xlabel(app.UIAxes2, "Infected / Medical / Dead");
            end
            ylabel(app.UIAxes2, "");
            grid(app.UIAxes2, 'on');
            
            
            %%%%%%
            %% TODO: duplicate all that ^^ for UIAxes3, UIAxes4 (cumulative plots)
           % app.UIAxes3
           % app.UIAxes4
           % selection_C
           
           %%%%%% 
        end
        
        function run(app)
            disp(app.inputs.propNC);
            [app.dailyResults, app.cumulativeResults] = runModel(app.inputs);
        end
        
        function runAndRedraw(app)
            app.run();
            app.redraw();
        end
        
        function loadInputs(app)
            app.icATExpHR_Slider.Value = app.inputs.icATExpHR;
            app.icATExpHR_Field.Value = app.inputs.icATExpHR;
            app.icATRecHR_Slider.Value = app.inputs.icATRecHR;
            app.icATRecHR_Field.Value = app.inputs.icATRecHR;
            app.icATExpLR_Slider.Value = app.inputs.icATExpLR;
            app.icATExpLR_Field.Value =app. inputs.icATExpLR;
            app.icATRecLR_Slider.Value = app.inputs.icATRecLR;
            app.icATRecLR_Field.Value = app.inputs.icATRecLR;
            app.icStaffExpHR_Slider.Value = app.inputs.icStaffExpHR;
            app.icStaffExpHR_Field.Value = app.inputs.icStaffExpHR;
            app.icStaffRecHR_Slider.Value = app.inputs.icStaffRecHR;
            app.icStaffRecHR_Field.Value = app.inputs.icStaffRecHR;
            app.icStaffExpLR_Slider.Value = app.inputs.icStaffExpLR;
            app.icStaffExpLR_Field.Value = app.inputs.icStaffExpLR;
            app.icStaffRecLR_Slider.Value = app.inputs.icStaffRecLR;
            app.icStaffRecLR_Field.Value = app.inputs.icStaffRecLR;
            app.icSExpC_Slider.Value = app.inputs.icSExpC;
            app.icSExpC_Field.Value = app.inputs.icSExpC;
            app.icSRecC_Slider.Value = app.inputs.icSRecC;
            app.icSRecC_Field.Value = app.inputs.icSRecC;
            app.icSExpNC_Slider.Value = app.inputs.icSExpNC;
            app.icSExpNC_Field.Value = app.inputs.icSExpNC;
            app.icSRecNC_Slider.Value = app.inputs.icSRecNC;
            app.icSRecNC_Field.Value = app.inputs.icSRecNC;
            app.soATHR_Slider.Value = app.inputs.soATHR;
            app.soATHR_Field.Value = app.inputs.soATHR;
            app.soATLR_Slider.Value = app.inputs.soATLR;
            app.soATLR_Field.Value = app.inputs.soATLR;
            app.soStaffHR_Slider.Value = app.inputs.soStaffHR;
            app.soStaffHR_Field.Value = app.inputs.soStaffHR;
            app.soStaffLR_Slider.Value = app.inputs.soStaffLR;
            app.soStaffLR_Field.Value = app.inputs.soStaffLR;
            app.soSC_Slider.Value = app.inputs.soSC;
            app.soSC_Field.Value = app.inputs.soSC;
            app.soSNC_Slider.Value = app.inputs.soSNC;
            app.soSNC_Field.Value = app.inputs.soSNC;
            app.propNC_Slider.Value = app.inputs.propNC;
            app.propNC_Field.Value = app.inputs.propNC;
            app.scaleTracking_Slider.Value = app.inputs.scaleTracking;
            app.scaleTracking_Field.Value = app.inputs.scaleTracking;
            app.incAsymp_Slider.Value = app.inputs.incAsymp;
            app.incAsymp_Field.Value = app.inputs.incAsymp;
            app.betaScaling_Slider.Value = app.inputs.betaScaling;
            app.betaScaling_Field.Value = app.inputs.betaScaling;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AT_HRCheckBox.Value = 1;
            app.AT_LRCheckBox.Value = 1;
            app.Staff_HRCheckBox.Value = 1;
            app.Staff_LRCheckBox.Value = 1;
            app.Students_CCheckBox.Value = 1;
            app.Students_NCCheckBox.Value = 1;
            app.ShowSusceptibleCheckBox.Value = 1;
            app.ShowHeldCheckBox.Value = 1;
            app.loadInputs();
            app.runAndRedraw();
        end

        % Value changed function: icSExpC_Field
        function icSExpC_FieldValueChanged(app, event)
            value = app.icSExpC_Field.Value;
            app.icSExpC_Slider.Value = value;
            app.inputs.icSExpC = value;
            app.runAndRedraw();
        end

        % Value changed function: icSExpNC_Field
        function icSExpNC_FieldValueChanged(app, event)
            value = app.icSExpNC_Field.Value;
            app.icSExpNC_Slider.Value = value;
            app.inputs.icSExpNC = value;
            app.runAndRedraw();
        end

        % Value changed function: icSExpC_Slider
        function icSExpC_SliderValueChanged(app, event)
            value = app.icSExpC_Slider.Value;
            app.icSExpC_Field.Value = value;
            app.inputs.icSExpNC = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icSRecC_Slider
        function icSRecC_SliderValueChanged(app, event)
            value = app.icSRecC_Slider.Value;
            app.icSRecC_Field.Value = value;
            app.inputs.icSRecC = value;
            app.runAndRedraw();
        end

        % Value changed function: icSExpNC_Slider
        function icSExpNC_SliderValueChanged(app, event)
            value = app.icSExpNC_Slider.Value;
            app.icSExpNC_Field.Value = value;
            app.inputs.icSExpNC = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icSRecNC_Slider
        function icSRecNC_SliderValueChanged(app, event)
            value = app.icSRecNC_Slider.Value;
            app.icSRecNC_Field.Value = value;
            app.inputs.icSRecNC = value;
            app.runAndRedraw();
        end

        % Value changed function: soSC_Field
        function soSC_FieldValueChanged(app, event)
            value = app.soSC_Field.Value;
            app.soSC_Slider.Value = value;
            app.inputs.soSC = value;
            app.runAndRedraw();
        end

        % Value changed function: soSNC_Field
        function soSNC_FieldValueChanged(app, event)
            value = app.soSNC_Field.Value;
            app.soSNC_Slider.Value = value;
            app.inputs.soSNC = value;
            app.runAndRedraw();
        end

        % Value changed function: soSC_Slider
        function soSC_SliderValueChanged(app, event)
            value = app.soSC_Slider.Value;
            app.soSC_Field.Value = value;
            app.inputs.soSC = value;
            app.runAndRedraw();
        end

        % Value changed function: soSNC_Slider
        function soSNC_SliderValueChanged(app, event)
            value = app.soSNC_Slider.Value;
            app.soSNC_Field.Value = value;
            app.inputs.soSNC = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icATExpHR_Slider
        function icATExpHR_SliderValueChanged(app, event)
            value = app.icATExpHR_Slider.Value;
            app.icATExpHR_Field.Value = value;
            app.inputs.icATExpHR= value;
            app.runAndRedraw();
        end

        % Value changed function: icATExpHR_Field
        function icATExpHR_FieldValueChanged(app, event)
            value = app.icATExpHR_Field.Value;
            app.icATExpHR_Slider.Value = value;
            app.inputs.icATExpHR= value;
            app.runAndRedraw();
            
        end

        % Value changed function: icATRecHR_Slider
        function icATRecHR_SliderValueChanged(app, event)
            value = app.icATRecHR_Slider.Value;
            app.icATRecHR_Field.Value = value;
            app.inputs.icATRecHR= value;
            app.runAndRedraw();
            
        end

        % Value changed function: icATRecHR_Field
        function icATRecHR_FieldValueChanged(app, event)
            value = app.icATRecHR_Field.Value;
            app.icATRecHR_Slider.Value = value;
            app.inputs.icATRecHR= value;
            app.runAndRedraw();
            
        end

        % Value changed function: icATExpLR_Slider
        function icATExpLR_SliderValueChanged(app, event)
            value = app.icATExpLR_Slider.Value;
            app.icATExpLR_Field.Value = value;
            app.inputs.icATExpLR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icATExpLR_Field
        function icATExpLR_FieldValueChanged(app, event)
            value = app.icATExpLR_Field.Value;
            app.icATExpLR_Slider.Value = value;
            app.inputs.icATExpLR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icATRecLR_Slider
        function icATRecLR_SliderValueChanged(app, event)
            value = app.icATRecLR_Slider.Value;
            app.icATRecLR_Field.Value = value;
            app.inputs.icATRecLR = value;
            app.runAndRedraw();
        end

        % Value changed function: icATRecLR_Field
        function icATRecLR_FieldValueChanged(app, event)
            value = app.icATRecLR_Field.Value;
            app.icATRecLR_Slider.Value = value;
            app.inputs.icATRecLR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: soATHR_Slider
        function soATHR_SliderValueChanged(app, event)
            value = app.soATHR_Slider.Value;
            app.soATHR_Field.Value = value;
            app.inputs.soATHR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: soATHR_Field
        function soATHR_FieldValueChanged(app, event)
            value = app.soATHR_Field.Value;
            app.soATHR_Slider.Value = value;
            app.inputs.soATHR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: soATLR_Slider
        function soATLR_SliderValueChanged(app, event)
            value = app.soATLR_Slider.Value;
            app.soATLR_Field.Value = value;
            app.inputs.soATLR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: soATLR_Field
        function soATLR_FieldValueChanged(app, event)
            value = app.soATLR_Field.Value;
            app.soATLR_Slider.Value = value;
            app.inputs.soATLR = value;
            app.runAndRedraw();
        end

        % Value changed function: icStaffExpHR_Slider
        function icStaffExpHR_SliderValueChanged(app, event)
            value = app.icStaffExpHR_Slider.Value;
            app.icStaffExpHR_Field.Value = value;
            app.inputs.icStaffExpHR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icStaffExpHR_Field
        function icStaffExpHR_FieldValueChanged(app, event)
            value = app.icStaffExpHR_Field.Value;
            app.icStaffExpHR_Slider.Value = value;
            app.inputs.icStaffExpHR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icStaffRecHR_Slider
        function icStaffRecHR_SliderValueChanged(app, event)
            value = app.icStaffRecHR_Slider.Value;
            app.icStaffRecHR_Field.Value = value;
            app.inputs.icStaffRecHR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icStaffRecHR_Field
        function icStaffRecHR_FieldValueChanged(app, event)
            value = app.icStaffRecHR_Field.Value;
            app.icStaffRecHR_Slider.Value = value;
            app.inputs.icStaffRecHR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icStaffExpLR_Slider
        function icStaffExpLR_SliderValueChanged(app, event)
            value = app.icStaffExpLR_Slider.Value;
            app.icStaffExpLR_Field.Value = value;
            app.inputs.icStaffExpLR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icStaffExpLR_Field
        function icStaffExpLR_FieldValueChanged(app, event)
            value = app.icStaffExpLR_Field.Value;
            app.icStaffExpLR_Slider.Value = value;
            app.inputs.icStaffExpLR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icStaffRecLR_Slider
        function icStaffRecLR_SliderValueChanged(app, event)
            value = app.icStaffRecLR_Slider.Value;
            app.icStaffRecLR_Field.Value = value;
            app.inputs.icStaffRecLR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icStaffRecLR_Field
        function icStaffRecLR_FieldValueChanged(app, event)
            value = app.icStaffRecLR_Field.Value;
            app.icStaffRecLR_Slider.Value = value;
            app.inputs.icStaffRecLR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: soStaffHR_Slider
        function soStaffHR_SliderValueChanged(app, event)
            value = app.soStaffHR_Slider.Value;
            app.soStaffHR_Field.Value = value;
            app.inputs.soStaffHR= value;
            app.runAndRedraw();
        end

        % Value changed function: soStaffHR_Field
        function soStaffHR_FieldValueChanged(app, event)
            value = app.soStaffHR_Field.Value;
            app.soStaffHR_Slider.Value = value;
            app.inputs.soStaffHR= value;
            app.runAndRedraw();
            
        end

        % Value changed function: soStaffLR_Slider
        function soStaffLR_SliderValueChanged(app, event)
            value = app.soStaffLR_Slider.Value;
            app.soStaffLR_Field.Value = value;
            app.inputs.soStaffLR= value;
            app.runAndRedraw();
            
        end

        % Value changed function: soStaffLR_Field
        function soStaffLR_FieldValueChanged(app, event)
            value = app.soStaffLR_Field.Value;
            app.soStaffLR_Slider.Value = value;
            app.inputs.soStaffLR= value;
            app.runAndRedraw();
            
        end

        % Value changed function: scaleTracking_Slider
        function scaleTracking_SliderValueChanged(app, event)
            value = app.scaleTracking_Slider.Value;
            app.scaleTracking_Field.Value = value;
            app.inputs.scaleTracking= value;
            app.runAndRedraw();
            
        end

        % Value changed function: scaleTracking_Field
        function scaleTracking_FieldValueChanged(app, event)
            value = app.scaleTracking_Field.Value;
            app.scaleTracking_Slider.Value = value;
            app.inputs.scaleTracking= value;
            app.runAndRedraw();
        end

        % Value changed function: propNC_Slider
        function propNC_SliderValueChanged(app, event)
            value = app.propNC_Slider.Value;
            app.propNC_Field.Value = value;
            app.inputs.propNC= value;
            app.runAndRedraw();            
        end

        % Value changed function: propNC_Field
        function propNC_FieldValueChanged(app, event)
            value = app.propNC_Field.Value;
            app.propNC_Slider.Value = value;
            app.inputs.propNC= value;
            app.runAndRedraw();
        end

        % Value changed function: incAsymp_Slider
        function incAsymp_SliderValueChanged(app, event)
            value = app.incAsymp_Slider.Value;
            app.incAsymp_Field.Value = value;
            app.inputs.incAsymp= value;
            app.runAndRedraw();
        end

        % Value changed function: incAsymp_Field
        function incAsymp_FieldValueChanged(app, event)
            value = app.incAsymp_Field.Value;
            app.incAsymp_Slider.Value = value;
            app.inputs.incAsymp= value;
            app.runAndRedraw();
        end

        % Button pushed function: InputPreset1_Button
        function InputPreset1_ButtonPushed(app, event)
            app.inputs = inputPreset(1);
            app.loadInputs();
            app.runAndRedraw();
        end

        % Button pushed function: InputPreset2_Button
        function InputPreset2_ButtonPushed(app, event)
            app.inputs = inputPreset(2);
            app.loadInputs();
            app.runAndRedraw();
        end

        % Button pushed function: InputPreset3_Button
        function InputPreset3_ButtonPushed(app, event)
            app.inputs = inputPreset(3);
            app.loadInputs();
            app.runAndRedraw();
        end

        % Value changed function: icSRecC_Field
        function icSRecC_FieldValueChanged(app, event)
            value = app.icSRecC_Field.Value;
            app.icSRecC_Slider.Value = value;
            app.inputs.icSRecC = value;
            app.runAndRedraw();
        end

        % Value changed function: AT_HRCheckBox
        function AT_HRCheckBoxValueChanged(app, event)
            value = app.AT_HRCheckBox.Value;
            app.selection(1) = value;
            app.redraw();
            
        end

        % Value changed function: AT_LRCheckBox
        function AT_LRCheckBoxValueChanged(app, event)
            value = app.AT_LRCheckBox.Value;
            app.selection(2) = value;
            app.redraw();
        end

        % Value changed function: Staff_HRCheckBox
        function Staff_HRCheckBoxValueChanged(app, event)
            value = app.Staff_HRCheckBox.Value;
            app.selection(3) = value;
            app.redraw();
        end

        % Value changed function: Staff_LRCheckBox
        function Staff_LRCheckBoxValueChanged(app, event)
            value = app.Staff_LRCheckBox.Value;
            app.selection(4) = value;
            app.redraw();
            
        end

        % Value changed function: Students_CCheckBox
        function Students_CCheckBoxValueChanged(app, event)
            value = app.Students_CCheckBox.Value;
            app.selection(5) = value;
            app.redraw();
        end

        % Value changed function: Students_NCCheckBox
        function Students_NCCheckBoxValueChanged(app, event)
            value = app.Students_NCCheckBox.Value;
            app.selection(6) = value;
            app.redraw();
        end

        % Value changed function: ShowHeldCheckBox
        function ShowHeldCheckBoxValueChanged(app, event)
            value = app.ShowHeldCheckBox.Value;
            app.showHeld = value;
            app.redraw();
        end

        % Value changed function: ShowSusceptibleCheckBox
        function ShowSusceptibleCheckBoxValueChanged(app, event)
            value = app.ShowSusceptibleCheckBox.Value;
            app.showSusceptible= value;
            app.redraw();
        end

        % Callback function
        function betaScaling_SliderValueChanged(app, event)
            value = app.betaScaling_Slider.Value;
            app.betaScaling_Field.Value = value;
            app.inputs.betaScaling= value;
            app.runAndRedraw();
            
        end

        % Value changed function: betaScaling_Field
        function betaScaling_FieldValueChanged(app, event)
            value = app.betaScaling_Field.Value;
            app.betaScaling_Slider.Value = value;
            app.inputs.betaScaling= value;
            app.runAndRedraw();
        end

        % Value changed function: betaScaling_Slider
        function betaScaling_SliderValueChanged2(app, event)
            value = app.betaScaling_Slider.Value;
            app.betaScaling_Field.Value = value;
            app.inputs.betaScaling= value;
            app.runAndRedraw();
        end

        % Value changed function: AT_HRCheckBox_C
        function AT_HRCheckBox_CValueChanged(app, event)
            value = app.AT_HRCheckBox_C.Value;
            %% TODO: update selectionCumulative, redraw
        end

        % Value changed function: Staff_HRCheckBox_C
        function Staff_HRCheckBox_CValueChanged(app, event)
            value = app.Staff_HRCheckBox_C.Value;
            %% TODO: update selectionCumulative, redraw
            app.selection_C(1) = value;
            app.redraw();
        end

        % Value changed function: AT_LRCheckBox_C
        function AT_LRCheckBox_CValueChanged(app, event)
            value = app.AT_LRCheckBox_C.Value;
            %% TODO: update selectionCumulative, redraw
            
        end

        % Value changed function: Staff_LRCheckBox_C
        function Staff_LRCheckBox_CValueChanged(app, event)
            value = app.Staff_LRCheckBox_C.Value;
            %% TODO: update selectionCumulative, redraw
            
        end

        % Value changed function: Students_CCheckBox_C
        function Students_CCheckBox_CValueChanged(app, event)
            value = app.Students_CCheckBox_C.Value;
            %% TODO: update selectionCumulative, redraw
        end

        % Value changed function: Students_NCCheckBox_C
        function Students_NCCheckBox_CValueChanged(app, event)
            value = app.Students_NCCheckBox_C.Value;
            %% TODO: update selectionCumulative, redraw
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID5CUIFigure = uifigure;
            app.COVID5CUIFigure.Name = 'COVID5C';
            app.ParametersInitialConiditionsPanel = uipanel(app.COVID5CUIFigure);
            app.ParametersInitialConiditionsPanel.Title = 'Parameters / Initial Coniditions';
            app.TabGroup = uitabgroup(app.ParametersInitialConiditionsPanel);
            app.StudentsTab = uitab(app.TabGroup);
            app.StudentsTab.Title = 'Students';
            app.soSC_Field = uieditfield(app.StudentsTab, 'numeric');
            app.soSC_Field.ValueChangedFcn = createCallbackFcn(app, @soSC_FieldValueChanged, true);
            app.soSNC_Field = uieditfield(app.StudentsTab, 'numeric');
            app.soSNC_Field.ValueChangedFcn = createCallbackFcn(app, @soSNC_FieldValueChanged, true);
            app.InitialConditionsPanel = uipanel(app.StudentsTab);
            app.InitialConditionsPanel.Title = 'Initial Conditions';
            app.icSExpC_Field = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.icSExpC_Field.ValueChangedFcn = createCallbackFcn(app, @icSExpC_FieldValueChanged, true);
            app.icSRecC_Field = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.icSRecC_Field.ValueChangedFcn = createCallbackFcn(app, @icSRecC_FieldValueChanged, true);
            app.icSExpNC_Field = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.icSExpNC_Field.ValueChangedFcn = createCallbackFcn(app, @icSExpNC_FieldValueChanged, true);
            app.icSRecNC_Field = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.ExposedCompliantLabel = uilabel(app.InitialConditionsPanel);
            app.ExposedCompliantLabel.Text = 'Exposed, Compliant';
            app.icSExpC_Slider = uislider(app.InitialConditionsPanel);
            app.icSExpC_Slider.Limits = [0 25];
            app.icSExpC_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icSExpC_Slider.ValueChangedFcn = createCallbackFcn(app, @icSExpC_SliderValueChanged, true);
            app.RecoveredCompliantLabel = uilabel(app.InitialConditionsPanel);
            app.RecoveredCompliantLabel.Text = 'Recovered, Compliant';
            app.icSRecC_Slider = uislider(app.InitialConditionsPanel);
            app.icSRecC_Slider.Limits = [0 25];
            app.icSRecC_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icSRecC_Slider.ValueChangedFcn = createCallbackFcn(app, @icSRecC_SliderValueChanged, true);
            app.ExposedNonCLabel = uilabel(app.InitialConditionsPanel);
            app.ExposedNonCLabel.Text = 'Exposed, Non-C';
            app.icSExpNC_Slider = uislider(app.InitialConditionsPanel);
            app.icSExpNC_Slider.Limits = [0 25];
            app.icSExpNC_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icSExpNC_Slider.ValueChangedFcn = createCallbackFcn(app, @icSExpNC_SliderValueChanged, true);
            app.RecoveredNonCLabel = uilabel(app.InitialConditionsPanel);
            app.RecoveredNonCLabel.Text = 'Recovered, Non-C';
            app.icSRecNC_Slider = uislider(app.InitialConditionsPanel);
            app.icSRecNC_Slider.Limits = [0 25];
            app.icSRecNC_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icSRecNC_Slider.ValueChangedFcn = createCallbackFcn(app, @icSRecNC_SliderValueChanged, true);
            app.InfectedContactsweekCompliantLabel = uilabel(app.StudentsTab);
            app.InfectedContactsweekCompliantLabel.Text = 'Infected Contacts / week (Compliant)';
            app.soSC_Slider = uislider(app.StudentsTab);
            app.soSC_Slider.Limits = [0 7];
            app.soSC_Slider.MajorTicks = [0 1 2 3 4 5 6 7];
            app.soSC_Slider.MajorTickLabels = {'', '', '', '', '', '', '', ''};
            app.soSC_Slider.ValueChangedFcn = createCallbackFcn(app, @soSC_SliderValueChanged, true);
            app.InfectedContactsweekNoncompliantLabel = uilabel(app.StudentsTab);
            app.InfectedContactsweekNoncompliantLabel.Text = 'Infected Contacts / week (Non-compliant)';
            app.soSNC_Slider = uislider(app.StudentsTab);
            app.soSNC_Slider.Limits = [0 7];
            app.soSNC_Slider.MajorTicks = [0 1 2 3 4 5 6 7];
            app.soSNC_Slider.MajorTickLabels = {'', '', '', '', '', '', '', ''};
            app.soSNC_Slider.ValueChangedFcn = createCallbackFcn(app, @soSNC_SliderValueChanged, true);
            app.AdminTeachersTab = uitab(app.TabGroup);
            app.AdminTeachersTab.Title = 'Admin/Teachers';
            app.InitialConditionsPanel_2 = uipanel(app.AdminTeachersTab);
            app.InitialConditionsPanel_2.Title = 'Initial Conditions';
            app.icATExpHR_Field = uieditfield(app.InitialConditionsPanel_2, 'numeric');
            app.icATExpHR_Field.ValueChangedFcn = createCallbackFcn(app, @icATExpHR_FieldValueChanged, true);
            app.icATRecHR_Field = uieditfield(app.InitialConditionsPanel_2, 'numeric');
            app.icATRecHR_Field.ValueChangedFcn = createCallbackFcn(app, @icATRecHR_FieldValueChanged, true);
            app.icATExpLR_Field = uieditfield(app.InitialConditionsPanel_2, 'numeric');
            app.icATExpLR_Field.ValueChangedFcn = createCallbackFcn(app, @icATExpLR_FieldValueChanged, true);
            app.icATRecLR_Field = uieditfield(app.InitialConditionsPanel_2, 'numeric');
            app.icATRecLR_Field.ValueChangedFcn = createCallbackFcn(app, @icATRecLR_FieldValueChanged, true);
            app.ExposedHighriskLabel = uilabel(app.InitialConditionsPanel_2);
            app.ExposedHighriskLabel.Text = 'Exposed, High-risk';
            app.icATExpHR_Slider = uislider(app.InitialConditionsPanel_2);
            app.icATExpHR_Slider.Limits = [0 25];
            app.icATExpHR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icATExpHR_Slider.ValueChangedFcn = createCallbackFcn(app, @icATExpHR_SliderValueChanged, true);
            app.ExposedLowriskLabel = uilabel(app.InitialConditionsPanel_2);
            app.ExposedLowriskLabel.Text = 'Exposed, Low-risk';
            app.icATExpLR_Slider = uislider(app.InitialConditionsPanel_2);
            app.icATExpLR_Slider.Limits = [0 25];
            app.icATExpLR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icATExpLR_Slider.ValueChangedFcn = createCallbackFcn(app, @icATExpLR_SliderValueChanged, true);
            app.RecoveredLowriskLabel = uilabel(app.InitialConditionsPanel_2);
            app.RecoveredLowriskLabel.Text = 'Recovered, Low-risk';
            app.icATRecLR_Slider = uislider(app.InitialConditionsPanel_2);
            app.icATRecLR_Slider.Limits = [0 25];
            app.icATRecLR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icATRecLR_Slider.ValueChangedFcn = createCallbackFcn(app, @icATRecLR_SliderValueChanged, true);
            app.RecoveredHighriskLabel = uilabel(app.InitialConditionsPanel_2);
            app.RecoveredHighriskLabel.Text = 'Recovered, High-risk';
            app.icATRecHR_Slider = uislider(app.InitialConditionsPanel_2);
            app.icATRecHR_Slider.Limits = [0 25];
            app.icATRecHR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icATRecHR_Slider.ValueChangedFcn = createCallbackFcn(app, @icATRecHR_SliderValueChanged, true);
            app.soATHR_Field = uieditfield(app.AdminTeachersTab, 'numeric');
            app.soATHR_Field.ValueChangedFcn = createCallbackFcn(app, @soATHR_FieldValueChanged, true);
            app.soATLR_Field = uieditfield(app.AdminTeachersTab, 'numeric');
            app.soATLR_Field.ValueChangedFcn = createCallbackFcn(app, @soATLR_FieldValueChanged, true);
            app.InfectedContactsweekHighriskLabel = uilabel(app.AdminTeachersTab);
            app.InfectedContactsweekHighriskLabel.Text = 'Infected Contacts / week (High-risk)';
            app.soATHR_Slider = uislider(app.AdminTeachersTab);
            app.soATHR_Slider.Limits = [0 7];
            app.soATHR_Slider.MajorTicks = [0 1 2 3 4 5 6 7];
            app.soATHR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', ''};
            app.soATHR_Slider.ValueChangedFcn = createCallbackFcn(app, @soATHR_SliderValueChanged, true);
            app.InfectedContactsweekLowriskLabel = uilabel(app.AdminTeachersTab);
            app.InfectedContactsweekLowriskLabel.Text = 'Infected Contacts / week (Low-risk)';
            app.soATLR_Slider = uislider(app.AdminTeachersTab);
            app.soATLR_Slider.Limits = [0 7];
            app.soATLR_Slider.MajorTicks = [0 1 2 3 4 5 6 7];
            app.soATLR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', ''};
            app.soATLR_Slider.ValueChangedFcn = createCallbackFcn(app, @soATLR_SliderValueChanged, true);
            app.StaffTab = uitab(app.TabGroup);
            app.StaffTab.Title = 'Staff';
            app.InitialConditionsPanel_3 = uipanel(app.StaffTab);
            app.InitialConditionsPanel_3.Title = 'Initial Conditions';
            app.soStaffHR_Field = uieditfield(app.StaffTab, 'numeric');
            app.soStaffHR_Field.ValueChangedFcn = createCallbackFcn(app, @soStaffHR_FieldValueChanged, true);
            app.soStaffLR_Field = uieditfield(app.StaffTab, 'numeric');
            app.soStaffLR_Field.ValueChangedFcn = createCallbackFcn(app, @soStaffLR_FieldValueChanged, true);
            app.InfectedContactsweekHighriskLabel_2 = uilabel(app.StaffTab);
            app.InfectedContactsweekHighriskLabel_2.Text = 'Infected Contacts / week (High-risk)';
            app.soStaffHR_Slider = uislider(app.StaffTab);
            app.soStaffHR_Slider.Limits = [0 7];
            app.soStaffHR_Slider.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7];
            app.soStaffHR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.soStaffHR_Slider.ValueChangedFcn = createCallbackFcn(app, @soStaffHR_SliderValueChanged, true);
            app.InfectedContactsweekLowriskLabel_2 = uilabel(app.StaffTab);
            app.InfectedContactsweekLowriskLabel_2.Text = 'Infected Contacts / week (Low-risk)';
            app.soStaffLR_Slider = uislider(app.StaffTab);
            app.soStaffLR_Slider.Limits = [0 7];
            app.soStaffLR_Slider.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7];
            app.soStaffLR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.soStaffLR_Slider.ValueChangedFcn = createCallbackFcn(app, @soStaffLR_SliderValueChanged, true);
            app.icStaffExpLR_Field = uieditfield(app.StaffTab, 'numeric');
            app.icStaffExpLR_Field.ValueChangedFcn = createCallbackFcn(app, @icStaffExpLR_FieldValueChanged, true);
            app.icStaffRecLR_Field = uieditfield(app.StaffTab, 'numeric');
            app.icStaffRecLR_Field.ValueChangedFcn = createCallbackFcn(app, @icStaffRecLR_FieldValueChanged, true);
            app.icStaffExpHR_Field = uieditfield(app.StaffTab, 'numeric');
            app.icStaffExpHR_Field.ValueChangedFcn = createCallbackFcn(app, @icStaffExpHR_FieldValueChanged, true);
            app.icStaffRecHR_Field = uieditfield(app.StaffTab, 'numeric');
            app.icStaffRecHR_Field.ValueChangedFcn = createCallbackFcn(app, @icStaffRecHR_FieldValueChanged, true);
            app.ExposedHighriskLabel_2 = uilabel(app.StaffTab);
            app.ExposedHighriskLabel_2.Text = 'Exposed, High-risk';
            app.icStaffExpHR_Slider = uislider(app.StaffTab);
            app.icStaffExpHR_Slider.Limits = [0 25];
            app.icStaffExpHR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icStaffExpHR_Slider.ValueChangedFcn = createCallbackFcn(app, @icStaffExpHR_SliderValueChanged, true);
            app.RecoveredHighriskLabel_2 = uilabel(app.StaffTab);
            app.RecoveredHighriskLabel_2.Text = 'Recovered, High-risk';
            app.icStaffRecHR_Slider = uislider(app.StaffTab);
            app.icStaffRecHR_Slider.Limits = [0 25];
            app.icStaffRecHR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icStaffRecHR_Slider.ValueChangedFcn = createCallbackFcn(app, @icStaffRecHR_SliderValueChanged, true);
            app.ExposedLowriskLabel_2 = uilabel(app.StaffTab);
            app.ExposedLowriskLabel_2.Text = 'Exposed, Low-risk';
            app.icStaffExpLR_Slider = uislider(app.StaffTab);
            app.icStaffExpLR_Slider.Limits = [0 25];
            app.icStaffExpLR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icStaffExpLR_Slider.ValueChangedFcn = createCallbackFcn(app, @icStaffExpLR_SliderValueChanged, true);
            app.RecoveredLowriskLabel_2 = uilabel(app.StaffTab);
            app.RecoveredLowriskLabel_2.Text = 'Recovered, Low-risk';
            app.icStaffRecLR_Slider = uislider(app.StaffTab);
            app.icStaffRecLR_Slider.Limits = [0 25];
            app.icStaffRecLR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icStaffRecLR_Slider.ValueChangedFcn = createCallbackFcn(app, @icStaffRecLR_SliderValueChanged, true);
            app.TracingEffectivenessLabel = uilabel(app.ParametersInitialConiditionsPanel);
            app.TracingEffectivenessLabel.Text = 'Tracing Effectiveness (%)';
            app.scaleTracking_Slider = uislider(app.ParametersInitialConiditionsPanel);
            app.scaleTracking_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', ''};
            app.scaleTracking_Slider.ValueChangedFcn = createCallbackFcn(app, @scaleTracking_SliderValueChanged, true);
            app.scaleTracking_Field = uieditfield(app.ParametersInitialConiditionsPanel, 'numeric');
            app.scaleTracking_Field.ValueChangedFcn = createCallbackFcn(app, @scaleTracking_FieldValueChanged, true);
            app.incAsymp_Field = uieditfield(app.ParametersInitialConiditionsPanel, 'numeric');
            app.incAsymp_Field.ValueChangedFcn = createCallbackFcn(app, @incAsymp_FieldValueChanged, true);
            app.AsymptomaticInfectLabel = uilabel(app.ParametersInitialConiditionsPanel);
            app.AsymptomaticInfectLabel.Text = 'Asymptomatic Infect. (%)';
            app.incAsymp_Slider = uislider(app.ParametersInitialConiditionsPanel);
            app.incAsymp_Slider.Limits = [0 1000];
            app.incAsymp_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', ''};
            app.incAsymp_Slider.ValueChangedFcn = createCallbackFcn(app, @incAsymp_SliderValueChanged, true);
            app.propNC_Field = uieditfield(app.ParametersInitialConiditionsPanel, 'numeric');
            app.propNC_Field.ValueChangedFcn = createCallbackFcn(app, @propNC_FieldValueChanged, true);
            app.NonCompliantLabel = uilabel(app.ParametersInitialConiditionsPanel);
            app.NonCompliantLabel.Text = 'Non-Compliant (%)';
            app.propNC_Slider = uislider(app.ParametersInitialConiditionsPanel);
            app.propNC_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', ''};
            app.propNC_Slider.ValueChangedFcn = createCallbackFcn(app, @propNC_SliderValueChanged, true);
            app.InputPreset1_Button = uibutton(app.ParametersInitialConiditionsPanel, 'push');
            app.InputPreset1_Button.ButtonPushedFcn = createCallbackFcn(app, @InputPreset1_ButtonPushed, true);
            app.InputPreset1_Button.Text = 'Realistic Goal';
            app.InputPreset2_Button = uibutton(app.ParametersInitialConiditionsPanel, 'push');
            app.InputPreset2_Button.ButtonPushedFcn = createCallbackFcn(app, @InputPreset2_ButtonPushed, true);
            app.InputPreset2_Button.Text = 'Students Misbehave';
            app.InputPreset3_Button = uibutton(app.ParametersInitialConiditionsPanel, 'push');
            app.InputPreset3_Button.ButtonPushedFcn = createCallbackFcn(app, @InputPreset3_ButtonPushed, true);
            app.InputPreset3_Button.Text = 'Everyone Misbehaves';
            app.betaScaling_Field = uieditfield(app.ParametersInitialConiditionsPanel, 'numeric');
            app.betaScaling_Field.ValueChangedFcn = createCallbackFcn(app, @betaScaling_FieldValueChanged, true);
            app.BetaScalingSliderLabel = uilabel(app.ParametersInitialConiditionsPanel);
            app.BetaScalingSliderLabel.Text = 'Beta Scaling (%)';
            app.betaScaling_Slider = uislider(app.ParametersInitialConiditionsPanel);
            app.betaScaling_Slider.Limits = [50 1000];
            app.betaScaling_Slider.MajorTicks = [50 100 150 200 250 300 350 400 450 500 550 600 650 700 750 800 850 900 950 1000];
            app.betaScaling_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.betaScaling_Slider.ValueChangedFcn = createCallbackFcn(app, @betaScaling_SliderValueChanged2, true);
            app.betaScaling_Slider.Value = 50;
            app.PlotsTabGroup = uitabgroup(app.COVID5CUIFigure);
            app.PlotsDailyTab = uitab(app.PlotsTabGroup);
            app.PlotsDailyTab.Title = 'Plots (Daily)';
            app.UIAxes1 = uiaxes(app.PlotsDailyTab);
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            app.AT_HRCheckBox = uicheckbox(app.PlotsDailyTab);
            app.AT_HRCheckBox.ValueChangedFcn = createCallbackFcn(app, @AT_HRCheckBoxValueChanged, true);
            app.AT_HRCheckBox.Text = 'AT_HR';
            app.AT_LRCheckBox = uicheckbox(app.PlotsDailyTab);
            app.AT_LRCheckBox.ValueChangedFcn = createCallbackFcn(app, @AT_LRCheckBoxValueChanged, true);
            app.AT_LRCheckBox.Text = 'AT_LR';
            app.Staff_HRCheckBox = uicheckbox(app.PlotsDailyTab);
            app.Staff_HRCheckBox.ValueChangedFcn = createCallbackFcn(app, @Staff_HRCheckBoxValueChanged, true);
            app.Staff_HRCheckBox.Text = 'Staff_HR';
            app.Staff_LRCheckBox = uicheckbox(app.PlotsDailyTab);
            app.Staff_LRCheckBox.ValueChangedFcn = createCallbackFcn(app, @Staff_LRCheckBoxValueChanged, true);
            app.Staff_LRCheckBox.Text = 'Staff_LR';
            app.Students_CCheckBox = uicheckbox(app.PlotsDailyTab);
            app.Students_CCheckBox.ValueChangedFcn = createCallbackFcn(app, @Students_CCheckBoxValueChanged, true);
            app.Students_CCheckBox.Text = 'Students_C';
            app.Students_NCCheckBox = uicheckbox(app.PlotsDailyTab);
            app.Students_NCCheckBox.ValueChangedFcn = createCallbackFcn(app, @Students_NCCheckBoxValueChanged, true);
            app.Students_NCCheckBox.Text = 'Students_NC';
            app.ShowSusceptibleCheckBox = uicheckbox(app.PlotsDailyTab);
            app.ShowSusceptibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowSusceptibleCheckBoxValueChanged, true);
            app.ShowSusceptibleCheckBox.Text = 'Show Susceptible';
            app.UIAxes2 = uiaxes(app.PlotsDailyTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.ShowHeldCheckBox = uicheckbox(app.PlotsDailyTab);
            app.ShowHeldCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHeldCheckBoxValueChanged, true);
            app.ShowHeldCheckBox.Text = 'Show Held';
            app.PlotscumulativeTab = uitab(app.PlotsTabGroup);
            app.PlotscumulativeTab.Title = 'Plots (cumulative)';
            app.UIAxes3 = uiaxes(app.PlotscumulativeTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.PlotscumulativeTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.AT_HRCheckBox_C = uicheckbox(app.PlotscumulativeTab);
            app.AT_HRCheckBox_C.ValueChangedFcn = createCallbackFcn(app, @AT_HRCheckBox_CValueChanged, true);
            app.AT_HRCheckBox_C.Text = 'AT_HR';
            app.AT_LRCheckBox_C = uicheckbox(app.PlotscumulativeTab);
            app.AT_LRCheckBox_C.ValueChangedFcn = createCallbackFcn(app, @AT_LRCheckBox_CValueChanged, true);
            app.AT_LRCheckBox_C.Text = 'AT_LR';
            app.Staff_HRCheckBox_C = uicheckbox(app.PlotscumulativeTab);
            app.Staff_HRCheckBox_C.ValueChangedFcn = createCallbackFcn(app, @Staff_HRCheckBox_CValueChanged, true);
            app.Staff_HRCheckBox_C.Text = 'Staff_HR';
            app.Staff_LRCheckBox_C = uicheckbox(app.PlotscumulativeTab);
            app.Staff_LRCheckBox_C.ValueChangedFcn = createCallbackFcn(app, @Staff_LRCheckBox_CValueChanged, true);
            app.Staff_LRCheckBox_C.Text = 'Staff_LR';
            app.Students_CCheckBox_C = uicheckbox(app.PlotscumulativeTab);
            app.Students_CCheckBox_C.ValueChangedFcn = createCallbackFcn(app, @Students_CCheckBox_CValueChanged, true);
            app.Students_CCheckBox_C.Text = 'Students_C';
            app.Students_NCCheckBox_C = uicheckbox(app.PlotscumulativeTab);
            app.Students_NCCheckBox_C.ValueChangedFcn = createCallbackFcn(app, @Students_NCCheckBox_CValueChanged, true);
            app.Students_NCCheckBox_C.Text = 'Students_NC';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID5CUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID5CUIFigure)
        end
    end
end